classdef periodic_noise_advance_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumofAreaSpinner               matlab.ui.control.Spinner
        NumofAreaSpinnerLabel          matlab.ui.control.Label
        ImageSizePanel                 matlab.ui.container.Panel
        ColumnEditField                matlab.ui.control.EditField
        ColumnEditFieldLabel           matlab.ui.control.Label
        RowEditField                   matlab.ui.control.EditField
        RowEditFieldLabel              matlab.ui.control.Label
        ExcludedArea2Panel             matlab.ui.container.Panel
        ColumnFinalEditField_2         matlab.ui.control.NumericEditField
        ColumnFinalEditField_2Label    matlab.ui.control.Label
        ColumnInitialEditField_2       matlab.ui.control.NumericEditField
        ColumnInitialEditField_2Label  matlab.ui.control.Label
        RowFinalEditField_2            matlab.ui.control.NumericEditField
        RowFinalEditField_2Label       matlab.ui.control.Label
        RowInitialEditField_2          matlab.ui.control.NumericEditField
        RowInitialEditField_2Label     matlab.ui.control.Label
        ExcludedArea1Panel             matlab.ui.container.Panel
        ColumnFinalEditField           matlab.ui.control.NumericEditField
        ColumnFinalEditFieldLabel      matlab.ui.control.Label
        ColumnInitialEditField         matlab.ui.control.NumericEditField
        ColumnInitialEditFieldLabel    matlab.ui.control.Label
        RowFinalEditField              matlab.ui.control.NumericEditField
        RowFinalEditFieldLabel         matlab.ui.control.Label
        RowInitialEditField            matlab.ui.control.NumericEditField
        RowInitialEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        parentApp % Description
    end
    
    methods (Access = private)
        
        function updateAreaStatus(app)
            if app.parentApp.excluded_area.size == 0
                app.ExcludedArea1Panel.Enable = 'off';
                app.ExcludedArea2Panel.Enable = 'off';
            elseif app.parentApp.excluded_area.size == 1
                app.ExcludedArea1Panel.Enable = 'on';
                app.ExcludedArea2Panel.Enable = 'off';
                app.parentApp.excluded_area.r1_i = app.RowInitialEditField.Value;
                app.parentApp.excluded_area.r1_f = app.RowFinalEditField.Value;
                app.parentApp.excluded_area.c1_i = app.ColumnInitialEditField.Value;
                app.parentApp.excluded_area.c1_f = app.ColumnFinalEditField.Value;
            else
                app.ExcludedArea1Panel.Enable = 'on';
                app.ExcludedArea2Panel.Enable = 'on';
                app.parentApp.excluded_area.r1_i = app.RowInitialEditField.Value;
                app.parentApp.excluded_area.r1_f = app.RowFinalEditField.Value;
                app.parentApp.excluded_area.c1_i = app.ColumnInitialEditField.Value;
                app.parentApp.excluded_area.c1_f = app.ColumnFinalEditField.Value;
                app.parentApp.excluded_area.r2_i = app.RowInitialEditField_2.Value;
                app.parentApp.excluded_area.r2_f = app.RowFinalEditField_2.Value;
                app.parentApp.excluded_area.c2_i = app.ColumnInitialEditField_2.Value;
                app.parentApp.excluded_area.c2_f = app.ColumnFinalEditField_2.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
             app.parentApp = parentApp;
            [M, N] = size(parentApp.input_image);
            if M > 0 && N > 0
                app.RowEditField.Value = string(M);
                app.ColumnEditField.Value = string(N);
                
                app.RowInitialEditField.Limits = [1,M];
                app.RowFinalEditField.Limits = [1,M];
                app.ColumnInitialEditField.Limits = [1,N];
                app.ColumnFinalEditField.Limits = [1,N];

                app.RowInitialEditField_2.Limits = [1,M];
                app.RowFinalEditField_2.Limits = [1,M];
                app.ColumnInitialEditField_2.Limits = [1,N];
                app.ColumnFinalEditField_2.Limits = [1,N];
            

                disp(app.RowInitialEditField.Limits);
                disp(app.parentApp.excluded_area.r1_i);
            app.RowInitialEditField.Value = app.parentApp.excluded_area.r1_i;
            app.RowFinalEditField.Value = app.parentApp.excluded_area.r1_f;
            app.ColumnInitialEditField.Value = app.parentApp.excluded_area.c1_i;
            app.ColumnFinalEditField.Value = app.parentApp.excluded_area.c1_f;
            app.RowInitialEditField_2.Value = app.parentApp.excluded_area.r2_i;
            app.RowFinalEditField_2.Value = app.parentApp.excluded_area.r2_f;
            app.ColumnInitialEditField_2.Value = app.parentApp.excluded_area.c2_i;
            app.ColumnFinalEditField_2.Value = app.parentApp.excluded_area.c2_f;
            end

            app.NumofAreaSpinner.Value = app.parentApp.excluded_area.size;
            updateAreaStatus(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.parentApp.AdvanceSettingButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: NumofAreaSpinner
        function NumofAreaSpinnerValueChanged(app, event)
            app.parentApp.excluded_area.size = app.NumofAreaSpinner.Value;
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: RowInitialEditField
        function RowInitialEditFieldValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: RowFinalEditField
        function RowFinalEditFieldValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: ColumnInitialEditField
        function ColumnInitialEditFieldValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: ColumnFinalEditField
        function ColumnFinalEditFieldValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: RowInitialEditField_2
        function RowInitialEditField_2ValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: RowFinalEditField_2
        function RowFinalEditField_2ValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: ColumnInitialEditField_2
        function ColumnInitialEditField_2ValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end

        % Value changed function: ColumnFinalEditField_2
        function ColumnFinalEditField_2ValueChanged(app, event)
            updateAreaStatus(app);
            app.parentApp.displayImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExcludedArea1Panel = uipanel(app.UIFigure);
            app.ExcludedArea1Panel.Title = 'Excluded Area 1';
            app.RowInitialEditFieldLabel = uilabel(app.ExcludedArea1Panel);
            app.RowInitialEditFieldLabel.Text = 'Row Initial';
            app.RowInitialEditField = uieditfield(app.ExcludedArea1Panel, 'numeric');
            app.RowInitialEditField.ValueChangedFcn = createCallbackFcn(app, @RowInitialEditFieldValueChanged, true);
            app.RowFinalEditFieldLabel = uilabel(app.ExcludedArea1Panel);
            app.RowFinalEditFieldLabel.Text = 'Row Final';
            app.RowFinalEditField = uieditfield(app.ExcludedArea1Panel, 'numeric');
            app.RowFinalEditField.ValueChangedFcn = createCallbackFcn(app, @RowFinalEditFieldValueChanged, true);
            app.ColumnInitialEditFieldLabel = uilabel(app.ExcludedArea1Panel);
            app.ColumnInitialEditFieldLabel.Text = 'Column Initial';
            app.ColumnInitialEditField = uieditfield(app.ExcludedArea1Panel, 'numeric');
            app.ColumnInitialEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnInitialEditFieldValueChanged, true);
            app.ColumnFinalEditFieldLabel = uilabel(app.ExcludedArea1Panel);
            app.ColumnFinalEditFieldLabel.Text = 'Column Final';
            app.ColumnFinalEditField = uieditfield(app.ExcludedArea1Panel, 'numeric');
            app.ColumnFinalEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnFinalEditFieldValueChanged, true);
            app.ExcludedArea2Panel = uipanel(app.UIFigure);
            app.ExcludedArea2Panel.Title = 'Excluded Area 2';
            app.RowInitialEditField_2Label = uilabel(app.ExcludedArea2Panel);
            app.RowInitialEditField_2Label.Text = 'Row Initial';
            app.RowInitialEditField_2 = uieditfield(app.ExcludedArea2Panel, 'numeric');
            app.RowInitialEditField_2.ValueChangedFcn = createCallbackFcn(app, @RowInitialEditField_2ValueChanged, true);
            app.RowFinalEditField_2Label = uilabel(app.ExcludedArea2Panel);
            app.RowFinalEditField_2Label.Text = 'Row Final';
            app.RowFinalEditField_2 = uieditfield(app.ExcludedArea2Panel, 'numeric');
            app.RowFinalEditField_2.ValueChangedFcn = createCallbackFcn(app, @RowFinalEditField_2ValueChanged, true);
            app.ColumnInitialEditField_2Label = uilabel(app.ExcludedArea2Panel);
            app.ColumnInitialEditField_2Label.Text = 'Column Initial';
            app.ColumnInitialEditField_2 = uieditfield(app.ExcludedArea2Panel, 'numeric');
            app.ColumnInitialEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColumnInitialEditField_2ValueChanged, true);
            app.ColumnFinalEditField_2Label = uilabel(app.ExcludedArea2Panel);
            app.ColumnFinalEditField_2Label.Text = 'Column Final';
            app.ColumnFinalEditField_2 = uieditfield(app.ExcludedArea2Panel, 'numeric');
            app.ColumnFinalEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColumnFinalEditField_2ValueChanged, true);
            app.ImageSizePanel = uipanel(app.UIFigure);
            app.ImageSizePanel.Title = 'Image Size';
            app.RowEditFieldLabel = uilabel(app.ImageSizePanel);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.ImageSizePanel, 'text');
            app.RowEditField.Editable = 'off';
            app.ColumnEditFieldLabel = uilabel(app.ImageSizePanel);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.ColumnEditField = uieditfield(app.ImageSizePanel, 'text');
            app.ColumnEditField.Editable = 'off';
            app.NumofAreaSpinnerLabel = uilabel(app.UIFigure);
            app.NumofAreaSpinnerLabel.Text = 'Num of Area';
            app.NumofAreaSpinner = uispinner(app.UIFigure);
            app.NumofAreaSpinner.Limits = [0 2];
            app.NumofAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofAreaSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = periodic_noise_advance_settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end