classdef z < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadImageButton            matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        LowPassFilteringTab        matlab.ui.container.Tab
        LPFType                    matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        LPFOutputImage             matlab.ui.control.UIAxes
        LPFFilter                  matlab.ui.control.UIAxes
        LPFSpectrum                matlab.ui.control.UIAxes
        HighPassFilteringTab       matlab.ui.container.Tab
        HPFType                    matlab.ui.control.DropDown
        TypeDropDownLabel_2        matlab.ui.control.Label
        HPFOutputImage             matlab.ui.control.UIAxes
        HPFFilter                  matlab.ui.control.UIAxes
        HPFSpectrum                matlab.ui.control.UIAxes
        EDITTHISTab                matlab.ui.container.Tab
        PeriodicNoiseFilteringTab  matlab.ui.container.Tab
        InputImage                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        input_image
        filter_mode
    end
    
    methods (Access = private)
        
        function updateimage(app, fname)
            try
                app.input_image = imread(fname);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            displayImages(app)
        end

        function displayImages(app)
            imagesc(app.InputImage, app.input_image);
            if strcmp(app.filter_mode.name, 'low pass filtering')
                if strcmp(app.filter_mode.type, 'ideal')
                    [image_out, filter, spectrum] = ilpf(app.input_image);
                elseif strcmp(app.filter_mode.type, 'butterworth')
                    [image_out, filter, spectrum] = blpf(app.input_image);
                elseif strcmp(app.filter_mode.type, 'gaussian')
                    [image_out, filter, spectrum] = glpf(app.input_image);
                end
                imagesc(app.LPFSpectrum, spectrum);
                imagesc(app.LPFFilter, filter);
                imagesc(app.LPFOutputImage, image_out);
            elseif strcmp(app.filter_mode.name, 'high pass filtering')
                if strcmp(app.filter_mode.type, 'ideal')
                    [image_out, filter, spectrum] = ihpf(app.input_image);
                elseif strcmp(app.filter_mode.type, 'butterworth')
                    [image_out, filter, spectrum] = bhpf(app.input_image);
                elseif strcmp(app.filter_mode.type, 'gaussian')
                    [image_out, filter, spectrum] = ghpf(app.input_image);
                end
                imagesc(app.HPFSpectrum, spectrum);
                imagesc(app.HPFFilter, filter);
                imagesc(app.HPFOutputImage, image_out);
            elseif strcmp(app.filter_mode.name, 'periodic noise filtering')
            end
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filter_mode.name = "low pass filtering";
            app.filter_mode.type = "ideal";

            app.InputImage.Colormap = gray(256);
            axis(app.InputImage, 'image');
            
            app.LPFOutputImage.Colormap = gray(256);
            axis(app.LPFOutputImage, 'image');
            app.LPFFilter.Colormap = gray(256);
            axis(app.LPFFilter, 'image');
            app.LPFSpectrum.Colormap = gray(256);
            axis(app.LPFSpectrum, 'image');

            app.HPFOutputImage.Colormap = gray(256);
            axis(app.HPFOutputImage, 'image');
            app.HPFFilter.Colormap = gray(256);
            axis(app.HPFFilter, 'image');
            app.HPFSpectrum.Colormap = gray(256);
            axis(app.HPFSpectrum, 'image');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: LPFType
        function LPFTypeValueChanged(app, event)
            app.filter_mode.type = lower(app.LPFType.Value);
            displayImages(app);
        end

        % Button down function: LowPassFilteringTab
        function LowPassFilteringTabButtonDown(app, event)
            app.filter_mode.name = 'low pass filtering';
            app.filter_mode.type = lower(app.LPFType.Value);
            displayImages(app);
        end

        % Button down function: HighPassFilteringTab
        function HighPassFilteringTabButtonDown(app, event)
            app.filter_mode.name = 'high pass filtering';
            app.filter_mode.type = lower(app.HPFType.Value);
            displayImages(app);
        end

        % Button down function: PeriodicNoiseFilteringTab
        function PeriodicNoiseFilteringTabButtonDown(app, event)
            app.filter_mode.name = 'periodic noise filtering';
        end

        % Value changed function: HPFType
        function HPFTypeValueChanged(app, event)
            app.filter_mode.type = lower(app.HPFType.Value);
            displayImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiaxes(app.UIFigure);
            title(app.InputImage, 'Input Image')
            xlabel(app.InputImage, 'X')
            ylabel(app.InputImage, 'Y')
            zlabel(app.InputImage, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LowPassFilteringTab = uitab(app.TabGroup);
            app.LowPassFilteringTab.Title = 'Low Pass Filtering';
            app.LowPassFilteringTab.ButtonDownFcn = createCallbackFcn(app, @LowPassFilteringTabButtonDown, true);
            app.LPFSpectrum = uiaxes(app.LowPassFilteringTab);
            title(app.LPFSpectrum, 'Fourier Spectrum')
            xlabel(app.LPFSpectrum, 'X')
            ylabel(app.LPFSpectrum, 'Y')
            zlabel(app.LPFSpectrum, 'Z')
            app.LPFFilter = uiaxes(app.LowPassFilteringTab);
            title(app.LPFFilter, 'Filter')
            xlabel(app.LPFFilter, 'X')
            ylabel(app.LPFFilter, 'Y')
            zlabel(app.LPFFilter, 'Z')
            app.LPFOutputImage = uiaxes(app.LowPassFilteringTab);
            title(app.LPFOutputImage, 'Output Image')
            xlabel(app.LPFOutputImage, 'X')
            ylabel(app.LPFOutputImage, 'Y')
            zlabel(app.LPFOutputImage, 'Z')
            app.TypeDropDownLabel = uilabel(app.LowPassFilteringTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.LPFType = uidropdown(app.LowPassFilteringTab);
            app.LPFType.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.LPFType.ValueChangedFcn = createCallbackFcn(app, @LPFTypeValueChanged, true);
            app.LPFType.Value = 'Ideal';
            app.HighPassFilteringTab = uitab(app.TabGroup);
            app.HighPassFilteringTab.Title = 'High Pass Filtering';
            app.HighPassFilteringTab.ButtonDownFcn = createCallbackFcn(app, @HighPassFilteringTabButtonDown, true);
            app.HPFSpectrum = uiaxes(app.HighPassFilteringTab);
            title(app.HPFSpectrum, 'Fourier Spectrum')
            xlabel(app.HPFSpectrum, 'X')
            ylabel(app.HPFSpectrum, 'Y')
            zlabel(app.HPFSpectrum, 'Z')
            app.HPFFilter = uiaxes(app.HighPassFilteringTab);
            title(app.HPFFilter, 'Filter')
            xlabel(app.HPFFilter, 'X')
            ylabel(app.HPFFilter, 'Y')
            zlabel(app.HPFFilter, 'Z')
            app.HPFOutputImage = uiaxes(app.HighPassFilteringTab);
            title(app.HPFOutputImage, 'Output Image')
            xlabel(app.HPFOutputImage, 'X')
            ylabel(app.HPFOutputImage, 'Y')
            zlabel(app.HPFOutputImage, 'Z')
            app.TypeDropDownLabel_2 = uilabel(app.HighPassFilteringTab);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.HPFType = uidropdown(app.HighPassFilteringTab);
            app.HPFType.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.HPFType.ValueChangedFcn = createCallbackFcn(app, @HPFTypeValueChanged, true);
            app.HPFType.Value = 'Ideal';
            app.EDITTHISTab = uitab(app.TabGroup);
            app.EDITTHISTab.Title = 'EDIT THIS';
            app.PeriodicNoiseFilteringTab = uitab(app.TabGroup);
            app.PeriodicNoiseFilteringTab.Title = 'Periodic Noise Filtering';
            app.PeriodicNoiseFilteringTab.ButtonDownFcn = createCallbackFcn(app, @PeriodicNoiseFilteringTabButtonDown, true);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end